classdef finalproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AddImagesToDataSetPanel  matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        TakePictureTab           matlab.ui.container.Tab
        TakeSnapButton           matlab.ui.control.Button
        NameEditFieldLabel       matlab.ui.control.Label
        NameEditField            matlab.ui.control.EditField
        LoadFromDeviceTab        matlab.ui.container.Tab
        BrowseButton             matlab.ui.control.Button
        EditField_2              matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
        AddImageButton           matlab.ui.control.Button
        SavetoFileButton         matlab.ui.control.Button
        LoadfromFileButton       matlab.ui.control.Button
        ResetDataButton          matlab.ui.control.Button
        TakeAttendancePanel      matlab.ui.container.Panel
        TabGroup_2               matlab.ui.container.TabGroup
        TakePictureTab_2         matlab.ui.container.Tab
        TakeSnapButton_2         matlab.ui.control.Button
        LoadFromDeviceTab_2      matlab.ui.container.Tab
        BrowseButton_2           matlab.ui.control.Button
        EditField_3              matlab.ui.control.EditField
        UIAxes_2                 matlab.ui.control.UIAxes
        UpdateDatabaseButton     matlab.ui.control.Button
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UITable                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        tempAddImage % Description
        faceDim=50 % Description
        faceData

        tempTestImage % Description
        eigenfaces
        order
        avgImg
        numOfImages
        selectedEigVecs
        weightedImage % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");
            try
                 result=EigenFace.loadFaces('FaceData');
                app.faceData=result.fData;
                
            catch
                app.faceData.names={};
                app.faceData.data={};
            end
            if (size(app.faceData.names)~=0)
                %z=zeros(size(app.faceData.names,2)*50,50,'uint8');
                %for i=1:size(app.faceData.names,2)
                %    z(((i-1)*50)+1:((i)*50),:)=app.faceData.data{i};
                    %z(((i-1)*50)+1:((i)*50),:)
               % end
               % imshow(z,'parent',app.UIAxes_3);  
               %a=montage((app.faceData.data));
               %imshow(a,'parent',app.UIAxes_3);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.tempAddImage=0;
            [file,path,]=uigetfile({'*.*';'*.jpg';'*.jpeg';'*.bmp';'*.gif';'*.tiff'});
            try
            app.EditField_2.Value=[path file];
            a=imread([path file]);
            app.tempAddImage=a;
            imshow(a,'parent',app.UIAxes);
            indices2 = strfind(file,'.'); 
            file=extractBetween(file,1,indices2(end)-1);
            app.UIAxes.Title.String=file;
            app.AddImageButton.Enable='on';
            catch 
                 uialert(app.UIFigure,'Image not found','Error');
            end
             figure(app.UIFigure);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.tempAddImage=0;
            v = app.EditField_2.Value;
            try
            a=imread(v);
            app.tempAddImage=a;
            imshow(a,'parent',app.UIAxes);
            file = v; 
              indices = strfind(file,'\');
             indices2 = strfind(file,'.'); 
            file=extractBetween(file,indices(end),indices2(end)-1);
            app.UIAxes.Title.String=file;
             app.AddImageButton.Enable='on';
            catch
                uialert(app.UIFigure,'Image not found','Error');
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.AddImageButton.Enable='off';
        end

        % Button pushed function: TakeSnapButton
        function TakeSnapButtonPushed(app, event)
            try
             cam=webcam(1);
             app.tempAddImage =snapshot(cam);
             imshow(app.tempAddImage,'parent',app.UIAxes);
             app.AddImageButton.Enable='on';
            catch
                 uialert(app.UIFigure,'Camera Error','Error');
            end
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            v = app.NameEditField.Value;
            app.UIAxes.Title.String=v;
            app.TakeSnapButton.Enable='on';
        end

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)
            
            try
            result=EigenFace.extractFace(app.tempAddImage,app.faceDim);
                if (result==0)
                    uialert(app.UIFigure,'No Face Detected!!!\nTry Again....','Face Detection Error');
                    app.AddImageButton.Enable='off';
                else
                    app.faceData=EigenFace.addImage(result,app.UIAxes.Title.String,app.faceData);
                    uialert(app.UIFigure,'Image Added','Success','Icon','success');
                    app.UpdateDatabaseButton.Enable='on';
                    app.AddImageButton.Enable='off';
                   
                end
            catch
                uialert(app.UIFigure,'Add Image Error','Error');
            end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            app.tempTestImage=0;
            [file,path,]=uigetfile({'*.*';'*.jpg';'*.jpeg';'*.bmp';'*.gif';'*.tiff'});
            try
            app.EditField_3.Value=[path file];
            a=imread([path file]);
            app.tempTestImage=a;
            imshow(a,'parent',app.UIAxes_2);
            indices2 = strfind(file,'.'); 
            file=extractBetween(file,1,indices2(end)-1);
            app.UIAxes_2.Title.String=file;
            figure(app.UIFigure);
             %now detect image
            try
                result=EigenFace.extractFace(app.tempTestImage,app.faceDim);
                if (result==0)
                    uialert(app.UIFigure,'No Face Detected!!!   Try Again....','Face Detection Error');
                else
                  %check face from eigon vectors
                  try
                    res=projectFunc.searchImage(result,app.eigenfaces,app.order,app.avgImg,app.numOfImages,app.selectedEigVecs,app.weightedImage);
                    if (res>0)
                    imshow(app.faceData.data{res},'parent',app.UIAxes_4);
                    app.UIAxes_4.Title.String=app.faceData.names{res};
                    app.UIAxes_4.Color='green';
                    app.UITable.Data(res,2)=table("P");
                    else
                        uialert(app.UIFigure,'No match found','Warning','Icon','warning');
                    end
                  catch
                       uialert(app.UIFigure,'Face Recognition Error','Face Recognition Error');
                  end
                end
             catch
                  uialert(app.UIFigure,'Face Detection Error','Face Detection Error');
            end
            catch 
                 uialert(app.UIFigure,'Image not found','Error');
            end
             
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            v = app.EditField_3.Value;
            try
            a=imread(v);
            app.tempTestImage=a;
            imshow(a,'parent',app.UIAxes_2);
            file = v; 
            indices = strfind(file,'\');
             indices2 = strfind(file,'.'); 
            file=extractBetween(file,indices(end),indices2(end)-1);
            app.UIAxes_2.Title.String=file;
             %now detect image
              try
                result=EigenFace.extractFace(app.tempTestImage,app.faceDim);
                if (result==0)
                    uialert(app.UIFigure,'No Face Detected!!!   Try Again....','Face Detection Error');
                else
                  %check face from eigon vectors
                  try
                    res=projectFunc.searchImage(result,app.eigenfaces,app.order,app.avgImg,app.numOfImages,app.selectedEigVecs,app.weightedImage);
                    if (res>0)
                    imshow(app.faceData.data{res},'parent',app.UIAxes_4);
                    app.UIAxes_4.Title.String=app.faceData.names{res};
                    app.UIAxes_4.Color='green';
                    app.UITable.Data(res,2)=table("P");
                    else
                        uialert(app.UIFigure,'No match found','Warning','Icon','warning');
                    end
                  catch
                       uialert(app.UIFigure,'Face Recognition Error','Face Recognition Error');
                  end
                end
             catch
                  uialert(app.UIFigure,'Face Detection Error','Face Detection Error');
              end
            catch
                uialert(app.UIFigure,'Image not found','Error');
            end
            
            
        end

        % Button pushed function: TakeSnapButton_2
        function TakeSnapButton_2Pushed(app, event)
             try
             cam=webcam(1);
             app.tempTestImage =snapshot(cam);
             imshow(app.tempTestImage,'parent',app.UIAxes_2);
              %now detect image
             try
                result=EigenFace.extractFace(app.tempTestImage,app.faceDim);
                if (result==0)
                    uialert(app.UIFigure,'No Face Detected!!!   Try Again....','Face Detection Error');
                else
                  %check face from eigon vectors
                  try
                    res=projectFunc.searchImage(result,app.eigenfaces,app.order,app.avgImg,app.numOfImages,app.selectedEigVecs,app.weightedImage);
                    if (res>0)
                    imshow(app.faceData.data{res},'parent',app.UIAxes_4);
                    app.UIAxes_4.Title.String=app.faceData.names{res};
                    app.UIAxes_4.Color='green';

                    app.UITable.Data(res,2)=table("P");
  
                    %title(app.faceData.names{res},'parent',app.UIAxes_3);
                    else
                        uialert(app.UIFigure,'No match found','Warning','Icon','warning');
                    end
                  catch
                       uialert(app.UIFigure,'Face Recognition Error','Face Recognition Error');
                  end
                end
             catch
                  uialert(app.UIFigure,'Face Detection Error','Face Detection Error');
             end
            catch
                 uialert(app.UIFigure,'Camera Error','Error');
            end
        end

        % Button pushed function: UpdateDatabaseButton
        function UpdateDatabaseButtonPushed(app, event)
            %create eigen faces
            try
                [app.eigenfaces,app.order,app.avgImg,app.numOfImages,app.selectedEigVecs,app.weightedImage]= projectFunc.getInputEigenFaces(app.faceData);
                if (size(app.faceData.names)~=0)
                    z=zeros(size(app.faceData.names,2)*50,50,'uint8');
                    for i=1:size(app.faceData.names,2)
                        z(((i-1)*50)+1:((i)*50),:)=app.faceData.data{i};
                        %z(((i-1)*50)+1:((i)*50),:)
                    end
                   imshow(z,'parent',app.UIAxes_3);
                   %tdata=table(transpose(app.faceData.names));
                   %set(app.UITable,'Data',tdata);
                   %set(app.UITable,'ColumnFormat',{'string'});
                   a=string(app.faceData.names);
                   %app.UITable.Data(:,1)=a;
                   for i=1:size(a,2)
                       s(i)="-";
                   end
                   set(app.UITable,'Data',table(transpose(a),transpose(s)));
                 end
                uialert(app.UIFigure,'Database Updated....','Success','Icon','success');
                app.UpdateDatabaseButton.Enable='off';
            catch
                uialert(app.UIFigure,'Eigen Vectors Error','Error');
            end
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            try
              %save('faceData','-struct','app.faceData');
              fData=app.faceData;
              save FaceData fData
               uialert(app.UIFigure,'File Saved....','Success','Icon','success');
            catch
                uialert(app.UIFigure,'Failed to save to file','Error');
            end
        end

        % Button pushed function: LoadfromFileButton
        function LoadfromFileButtonPushed(app, event)
            try
                result=EigenFace.loadFaces('FaceData');
                app.faceData=result.fData;
                app.UpdateDatabaseButton.Enable='on';
                uialert(app.UIFigure,'File Loaded....','Success','Icon','success');
            catch
                uialert(app.UIFigure,'Failed to Load from file','Error');
            end
        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            app.faceData.names={};
            app.faceData.data={};
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AddImagesToDataSetPanel = uipanel(app.UIFigure);
            app.AddImagesToDataSetPanel.Title = 'Add Images To DataSet';
            app.TabGroup = uitabgroup(app.AddImagesToDataSetPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TakePictureTab = uitab(app.TabGroup);
            app.TakePictureTab.Title = 'Take Picture';
            app.TakeSnapButton = uibutton(app.TakePictureTab, 'push');
            app.TakeSnapButton.ButtonPushedFcn = createCallbackFcn(app, @TakeSnapButtonPushed, true);
            app.TakeSnapButton.Enable = 'off';
            app.TakeSnapButton.Text = 'Take Snap';
            app.NameEditFieldLabel = uilabel(app.TakePictureTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.TakePictureTab, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.LoadFromDeviceTab = uitab(app.TabGroup);
            app.LoadFromDeviceTab.Title = 'Load From Device';
            app.BrowseButton = uibutton(app.LoadFromDeviceTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EditField_2 = uieditfield(app.LoadFromDeviceTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.UIAxes = uiaxes(app.AddImagesToDataSetPanel);
            title(app.UIAxes, 'Title')
            app.AddImageButton = uibutton(app.AddImagesToDataSetPanel, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Enable = 'off';
            app.AddImageButton.Text = 'Add Image';
            app.SavetoFileButton = uibutton(app.AddImagesToDataSetPanel, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = 'Save to File';
            app.LoadfromFileButton = uibutton(app.AddImagesToDataSetPanel, 'push');
            app.LoadfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromFileButtonPushed, true);
            app.LoadfromFileButton.Text = 'Load from File';
            app.ResetDataButton = uibutton(app.AddImagesToDataSetPanel, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Text = 'Reset Data';
            app.TakeAttendancePanel = uipanel(app.UIFigure);
            app.TakeAttendancePanel.Title = 'Take Attendance';
            app.TabGroup_2 = uitabgroup(app.TakeAttendancePanel);
            app.TakePictureTab_2 = uitab(app.TabGroup_2);
            app.TakePictureTab_2.Title = 'Take Picture';
            app.TakeSnapButton_2 = uibutton(app.TakePictureTab_2, 'push');
            app.TakeSnapButton_2.ButtonPushedFcn = createCallbackFcn(app, @TakeSnapButton_2Pushed, true);
            app.TakeSnapButton_2.Text = 'Take Snap';
            app.LoadFromDeviceTab_2 = uitab(app.TabGroup_2);
            app.LoadFromDeviceTab_2.Title = 'Load From Device';
            app.BrowseButton_2 = uibutton(app.LoadFromDeviceTab_2, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.EditField_3 = uieditfield(app.LoadFromDeviceTab_2, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.UIAxes_2 = uiaxes(app.TakeAttendancePanel);
            title(app.UIAxes_2, 'Title')
            app.UpdateDatabaseButton = uibutton(app.UIFigure, 'push');
            app.UpdateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDatabaseButtonPushed, true);
            app.UpdateDatabaseButton.Enable = 'off';
            app.UpdateDatabaseButton.Text = 'Update Database';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Database Images')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Result Image')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Attendance'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = finalproject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end